classdef CarGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        CarImage  matlab.ui.control.Image
    end

    
    properties (Access = private)
        LeftPressed = false
        RightPressed = false
        UpPressed = false
        DownPressed = false
        GameTimer
    end
    
    methods (Access = private)
        
        function updateGame(app)
            speed = 5;
            pos = app.CarImage.Position;
            figPos = app.UIFigure.Position;
            if app.LeftPressed
                pos(1) = pos(1) - speed;
            end
            if app.RightPressed
                pos(1) = pos(1) + speed;
            end
            pos(1) = max(0, min(pos(1), figPos(3) - pos(3)));
            app.CarImage.Position = pos;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Car Game';
            app.UIFigure.Icon = 'CarIcon.png';
            app.GameTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.01, ...      % 10 ms = 100 FPS
                'TimerFcn', @(~,~)updateGame(app));
            start(app.GameTimer);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)

            switch event.Key
                case 'leftarrow'
                    app.LeftPressed = true;
                case 'rightarrow'
                    app.RightPressed = true;
            end
            
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
          
            switch event.Key
                case 'leftarrow'
                    app.LeftPressed = false;
                case 'rightarrow'
                    app.RightPressed = false;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            selection = uiconfirm(app.UIFigure,...
                'Exit the game?',...
                'Confirm Exit',...
                'Options',{'Yes','No'});
            if strcmp(selection, 'Yes')
                if ~isempty(app.GameTimer) && isvalid(app.GameTimer)
                    stop(app.GameTimer);
                    delete(app.GameTimer);
                end
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.CarImage = uiimage(app.UIFigure);
            app.CarImage.ImageSource = fullfile(pathToMLAPP, 'CarSprite.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end